classdef theApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        B4Button   matlab.ui.control.Button
        A4SButton  matlab.ui.control.Button
        A4Button   matlab.ui.control.Button
        G4Button   matlab.ui.control.Button
        G4SButton  matlab.ui.control.Button
        F4SButton  matlab.ui.control.Button
        F4Button   matlab.ui.control.Button
        E4Button   matlab.ui.control.Button
        D4SButton  matlab.ui.control.Button
        D4Button   matlab.ui.control.Button
        C4SButton  matlab.ui.control.Button
        C4Button   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: C4Button
        function C4(app, event)
            note = calumnote(0.5,40)
            Sound(note,44100)
        end

        % Button pushed function: C4SButton
        function C4S(app, event)
            note = calumnote(0.5,41)
            Sound(note,44100)
        end

        % Button pushed function: D4Button
        function D4(app, event)
            note = calumnote(0.5,42)
            Sound(note,44100)
        end

        % Button pushed function: D4SButton
        function D4S(app, event)
            note = calumnote(0.5,43)
            Sound(note,44100)
        end

        % Button pushed function: E4Button
        function E4(app, event)
            note = calumnote(0.5,44)
            Sound(note,44100)
        end

        % Button pushed function: F4Button
        function F4(app, event)
            note = calumnote(0.5,45)
            Sound(note,44100)
        end

        % Button pushed function: F4SButton
        function F4S(app, event)
            note = calumnote(0.5,46)
            Sound(note,44100)
        end

        % Button pushed function: G4Button
        function G4(app, event)
            note = calumnote(0.5,47)
            Sound(note,44100)
        end

        % Button pushed function: G4SButton
        function G4S(app, event)
            note = calumnote(0.5,48)
            Sound(note,44100)
        end

        % Button pushed function: A4Button
        function A4(app, event)
            note = calumnote(0.5,49)
            Sound(note,44100)
        end

        % Button pushed function: A4SButton
        function A4S(app, event)
            note = calumnote(0.5,50)
            Sound(note,44100)
        end

        % Button pushed function: B4Button
        function B4(app, event)
            note = calumnote(0.5,51)
            Sound(note,44100)
        end

        % Window key press function: UIFigure
        function press(app, event)
            key = event.Key;
            switch key
                case 'a' 
                    note=calumnote(0.5,40);
                case 'w' 
                    note=calumnote(0.5,41);
                case 's' 
                    note=calumnote(0.5,42);
                case 'e' 
                    note=calumnote(0.5,43);
                case 'd'
                    note=calumnote(0.5,44);
                case 'f'
                    note=calumnote(0.5,45);
                case 't'
                    note=calumnote(0.5,46);
                case 'g'
                    note=calumnote(0.5,47);
                case 'y'
                    note=calumnote(0.5,48);
                case 'h'
                    note=calumnote(0.5,49);
                case 'u'
                    note=calumnote(0.5,50);
                case 'j'
                    note=calumnote(0.5,51);


            end
            sound(note,44100)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @press, true);
            app.C4Button = uibutton(app.UIFigure, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4, true);
            app.C4Button.Tag = 'C4';
            app.C4Button.Text = 'C4';
            app.C4SButton = uibutton(app.UIFigure, 'push');
            app.C4SButton.ButtonPushedFcn = createCallbackFcn(app, @C4S, true);
            app.C4SButton.Tag = 'C4S';
            app.C4SButton.Text = 'C4S';
            app.D4Button = uibutton(app.UIFigure, 'push');
            app.D4Button.ButtonPushedFcn = createCallbackFcn(app, @D4, true);
            app.D4Button.Tag = 'D4';
            app.D4Button.Text = 'D4';
            app.D4SButton = uibutton(app.UIFigure, 'push');
            app.D4SButton.ButtonPushedFcn = createCallbackFcn(app, @D4S, true);
            app.D4SButton.Tag = 'D4S';
            app.D4SButton.Text = 'D4S';
            app.E4Button = uibutton(app.UIFigure, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4, true);
            app.E4Button.Tag = 'E4';
            app.E4Button.Text = 'E4';
            app.F4Button = uibutton(app.UIFigure, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4, true);
            app.F4Button.Tag = 'F4';
            app.F4Button.Text = 'F4';
            app.F4SButton = uibutton(app.UIFigure, 'push');
            app.F4SButton.ButtonPushedFcn = createCallbackFcn(app, @F4S, true);
            app.F4SButton.Tag = 'F4S';
            app.F4SButton.Text = 'F4S';
            app.G4SButton = uibutton(app.UIFigure, 'push');
            app.G4SButton.ButtonPushedFcn = createCallbackFcn(app, @G4S, true);
            app.G4SButton.Tag = 'G4S';
            app.G4SButton.Text = 'G4S';
            app.G4Button = uibutton(app.UIFigure, 'push');
            app.G4Button.ButtonPushedFcn = createCallbackFcn(app, @G4, true);
            app.G4Button.Tag = 'G4';
            app.G4Button.Text = 'G4';
            app.A4Button = uibutton(app.UIFigure, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4, true);
            app.A4Button.Tag = 'A4';
            app.A4Button.Text = 'A4';
            app.A4SButton = uibutton(app.UIFigure, 'push');
            app.A4SButton.ButtonPushedFcn = createCallbackFcn(app, @A4S, true);
            app.A4SButton.Tag = 'A4S';
            app.A4SButton.Text = 'A4S';
            app.B4Button = uibutton(app.UIFigure, 'push');
            app.B4Button.ButtonPushedFcn = createCallbackFcn(app, @B4, true);
            app.B4Button.Tag = 'B4';
            app.B4Button.Text = 'B4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = theApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end